classdef BTL_KTRB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OUTPUTPanel          matlab.ui.container.Panel
        Yaw_Angle            matlab.ui.control.NumericEditField
        YawEditFieldLabel    matlab.ui.control.Label
        Roll_Angle           matlab.ui.control.NumericEditField
        RollEditFieldLabel   matlab.ui.control.Label
        Pitch_Angle          matlab.ui.control.NumericEditField
        PitchEditFieldLabel  matlab.ui.control.Label
        Z_EndEffector        matlab.ui.control.NumericEditField
        PzEditFieldLabel     matlab.ui.control.Label
        Y_EndEffector        matlab.ui.control.NumericEditField
        PyEditFieldLabel     matlab.ui.control.Label
        X_EndEffector        matlab.ui.control.NumericEditField
        PxEditFieldLabel     matlab.ui.control.Label
        INPUTPanel           matlab.ui.container.Panel
        ClearButton          matlab.ui.control.StateButton
        theta_box_4          matlab.ui.control.NumericEditField
        theta_box_3          matlab.ui.control.NumericEditField
        theta_box_2          matlab.ui.control.NumericEditField
        theta_box_1          matlab.ui.control.NumericEditField
        ResetButton          matlab.ui.control.Button
        ForwardButton        matlab.ui.control.Button
        theta2Slider         matlab.ui.control.Slider
        theta2SliderLabel    matlab.ui.control.Label
        theta4Slider         matlab.ui.control.Slider
        theta4SliderLabel    matlab.ui.control.Label
        theta3Slider         matlab.ui.control.Slider
        theta3SliderLabel    matlab.ui.control.Label
        theta1Slider         matlab.ui.control.Slider
        theta1SliderLabel    matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            % Khai báo các ma trận chuyển đổi   
        T01 = zeros(4);             % Khớp 0 đến khớp 1
        T12 = zeros(4);             % Khớp 1 đến khớp 2
        T23 = zeros(4);             % Khớp 2 đến khớp 3
        T34 = zeros(4);             % Khớp 3 đến khớp 4
        T02 = zeros(4);             % Khớp 0 đến khớp 2
        T03 = zeros(4);             % Khớp 0 đến khớp 3
        T04 = zeros(4);             % Khớp 0 đến khớp 4
            % Tham số D-H (Denavit-Hartenberg) của các khớp

        theta = [   0     pi/2     -pi/2    0];
        d     = [   77    0     0    0];
        a     = [   0     130   124  126];
        alpha = [   pi/2   0    0   0];

        % d1 = 77; 
        % a2 = 130;
        % a3 = 124;
        % a4 = 126;
        % 
        % -------- Góc quay của các khớp --------
        old_theta = [0, 0, 0, 0];           % Góc quay trước đó của các khớp
        new_theta = [0, 0, 0, 0];           % Góc quay hiện tại của các khớp
        % theta1 = 0; 
        % theta2 = 0; 
        % theta3 = 0;
        % theta4 = 0;
        % Ma trận xoay theo trục y 
        Rotate_Y = [cos(pi/2)       0       sin(pi/2)       0   ; 
                    0               1       0               0   ; 
                    -sin(pi/2)      0       cos(pi/2)       0   ; 
                    0               0       0               1  ];
        

        % -------- Các trục và nhãn cho (base), khớp và end-effector --------
    % Trục tọa độ và label của base             % Trục tọa độ và label của khớp 1
    base_X;             base_Xlabel;            joint1_X;           joint1_Xlabel; 
    base_Y;             base_Ylabel;            joint1_Y;           joint1_Ylabel;
    base_Z;             base_Zlabel;            joint1_Z;           joint1_Zlabel;  

    % Trục tọa độ và label của khớp 2           % Trục tọa độ và label của khớp 3
    joint2_X;              joint2_Xlabel;       joint3_X;           joint3_Xlabel; 
    joint2_Y;              joint2_Ylabel;       joint3_Y;           joint3_Ylabel;
    joint2_Z;              joint2_Zlabel;       joint3_Z;           joint3_Zlabel;
    
    % Trục tọa độ và label của khớp 4           % Trục tọa độ và label của end-effector
    joint4_X;           joint4_Xlabel;          endEffector_X;      endEffector_Xlabel;
    joint4_Y;           joint4_Ylabel;          endEffector_Y;      endEffector_Ylabel;
    joint4_Z;           joint4_Zlabel;          endEffector_Z;      endEffector_Zlabel; 
    
    Joint1Angle;Joint2Angle;Joint3Angle;Joint4Angle
    % Biến vẽ đường đi 
    robotPath;
    % Biến lưu trữ kết quả các góc Euler Roll Pitch Yaw (deg)
    eulerAngles = []; 

    end
    
    methods (Access = private)
        
        function T = DH_Matrix(app, n)
                 T =       [cos(app.theta(n)),  -cos(app.alpha(n))*sin(app.theta(n)),  sin(app.alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                           sin(app.theta(n)),   cos(app.alpha(n))*cos(app.theta(n)), -sin(app.alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                           0,                   sin(app.alpha(n)),                    cos(app.alpha(n)),                   app.d(n);
                           0,                   0,                                    0,                                   1                        ];
        end
        
        function Draw_Robot(app)
        % Xóa hình ảnh robot cũ trên trục tọa độ
                hold(app.UIAxes, "off");
        
        % Các ma trận biến đổi Denavit-Hartenberg
                app.T01 = DH_Matrix(app,1);
                app.T12 = DH_Matrix(app,2);
                app.T23 = DH_Matrix(app,3);
                app.T34 = DH_Matrix(app,4);
               
                app.T02 = app.T01*app.T12;
                app.T03 = app.T02*app.T23;
                app.T04 = app.T03*app.T34;


        %% Base
        [X, Y, Z] = cylinder(40, 50); %
        Z = Z * 50;                  % 
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.00 0.45 0.74]); % Base
        hold(app.UIAxes, "on");
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.00 0.45 0.74]); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.00 0.45 0.74]); % Mặt trên
        % Trục khớp 1 
        
        [X, Y, Z] = cylinder(10, 50);
        Z = Z * 77;
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); % Mặt trên

        %% --------------- Vẽ Joint 1 --------------- khoi nằm ngang đầu tiên 
        % Tạo hình trụ cho Joint 1
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 70 - 35;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))];% tạo ma trân biểu diễn các điểm ở mặt trên
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];% tạo ma trân biểu diễn các điểm ở mặt dưới 
        A_new = app.T01 * A; B_new = app.T01 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        % 
        % % Vẽ Joint 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); % Mặt đáy trên
        %% --------------- Vẽ Joint 2 --------------- 
        % Tạo hình trụ cho Joint 2
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 50 - 25;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T02 * A; B_new = app.T02 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Joint 2
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); % Mặt đáy trên
        %% --------------- Vẽ Joint 3 --------------- 
        % Tạo hình trụ cho Joint 3
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 50 - 25;               % Chiều cao và dịch trục Z
        
        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T03 * A; B_new = app.T03 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Joint 3
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); % Mặt đáy trên
        %% --------------- Vẽ End-effector --------------- 
    % Tạo end-effector
    x_e = [ 10,   50,   40,   30,   20,   10,   5,   10,   20,   30,   40,   50,   10];
    z_e = [-10,  -10,  -20,  -20,  -30,  -30,    0,   30,   30,   20,   20,   10,   10];
    
    y_e = [  5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5,    5 ;...
            -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5,   -5];        % Tạo hai mặt
    
    x_e = repmat(x_e, 2, 1); % Tạo ma trận 2 hàng từ x_e
    z_e = repmat(z_e, 2, 1); % Tạo ma trận 2 hàng từ z_e
    
    % Biến đổi DH cho effector
        A = [x_e(1, :); y_e(1, :); z_e(1, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt dưới theo vecto cột, 
        B = [x_e(2, :); y_e(2, :); z_e(2, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt trên theo vecto cột,
        A_new = app.T04 * A;        % Nhân các điểm của ma trận A với T04
        B_new = app.T04 * B;        % Nhân các điểm của ma trận B với T04
    
        x_e(1, :) = A_new(1, :);        y_e(1, :) = A_new(2, :);        % Cập nhật lại các tọa độ x y z
        x_e(2, :) = B_new(1, :);        y_e(2, :) = B_new(2, :);
        z_e(1, :) = A_new(3, :);        z_e(2, :) = B_new(3, :);
    
    % Vẽ effector
    surf(app.UIAxes, x_e, y_e, z_e, 'FaceColor', 'b');
    fill3(app.UIAxes, x_e(1, :), y_e(1, :), z_e(1, :), 'b'); % Mặt trên
    fill3(app.UIAxes, x_e(2, :), y_e(2, :), z_e(2, :), 'b'); % Mặt dưới

    x_endEffector = 50;
    y_endEffector = 0;
    z_endEffector = 0;
   
    P_endEffector = [x_endEffector; y_endEffector; z_endEffector; 1];            % Tọa độ điểm P của end effector

    P_endEffector = app.T04 * P_endEffector;                   % Nhân các điểm của ma trận P với T04
    
    x_endEffector = P_endEffector(1, 1);                       % Cập nhật lại các tọa độ x y z
    y_endEffector = P_endEffector(2, 1);
    z_endEffector = P_endEffector(3, 1);
   
    
    app.X_EndEffector.Value = x_endEffector;                % Hiển thị vị trí của effector lên box value
    app.Y_EndEffector.Value = y_endEffector;
    app.Z_EndEffector.Value = z_endEffector;

    % Thêm tọa độ mới vào đường đi
    [~, n] = size(app.robotPath);
    app.robotPath(1, n + 1) = x_endEffector;   % ban đầu khai báo biến robotPath, và biến n là số cột của biến robotPath
    app.robotPath(2, n + 1) = y_endEffector;   % cập nhật các giá trị x y z của end effector, đồng thời tăng số cột lên 1  
    app.robotPath(3, n + 1) = z_endEffector;   % để tạo cột cho các giá trị x y z tiếp theo    
   % Vẽ đường đi trên trục tọa độ UIAxes
    plot3(app.UIAxes, app.robotPath(1, :), app.robotPath(2, :), app.robotPath(3, :), 'LineWidth', 2, 'Color', 'b');
   % Tính toán góc Euler từ ma trận biến đổi T03 (3 góc quay theo trục x y z: roll ψ, pitch θ, yaw ϕ)
        %       [  cϕ * cθ     cϕ * sθ * sψ - sϕ * cψ      cϕ * sθ * cψ + sϕ * sψ        -   ]
        % T04 = |  sϕ * cθ     sϕ * sθ * sψ + cϕ * cψ      sϕ * sθ * cψ - cϕ * sψ        -   |
        %       |  -sθ         cθ * sψ                     cθ * cψ                       -   |
        %       [    0             0                         0                           1   ]
    cospitch = sqrt((app.T04(1, 1))^2 + (app.T04(2, 1))^2);     %cθ = sqrt[ (cϕ * cθ)^2 + (sϕ * cθ)^2 ]
    pitch = atan2(-app.T03(3, 1), cospitch);                    %sθ = -T04(3, 1) ( r31 ) 

    if pitch == pi / 2   
        yaw = 0;
        roll = atan2(app.T04(1, 2), app.T04(2, 2));       
    elseif  pitch == -pi / 2 
        yaw = 0; 
        roll = -atan2(app.T04(1, 2), app.T04(2, 2));   
    else 
        yaw =  atan2(app.T04(2,1)/cos(pitch), app.T04(1,1)/cos(pitch))  ;           % atan2(r21/costheta , r11 / costheta )
        roll = atan2(app.T04(3,2)/cos(pitch), app.T04(3,3)/cos(pitch))  ;           % atan2(r32/costheta , r33/costheta ) 
    end 
    app.eulerAngles = [roll pitch yaw] * (180 / pi); % Chuyển đổi từ radian sang độ  
    % Cập nhật giá trị góc Euler vào các ô nhập liệu
    app.Roll_Angle.Value    = app.eulerAngles(1);
    app.Pitch_Angle.Value   = app.eulerAngles(2);
    app.Yaw_Angle.Value     = app.eulerAngles(3);    
    %% --------------- Vẽ Link 1 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10);  % Bán kính 10
        Z = Z * 130 - 130;           % Chiều cao và dịch trục Z
        
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T02 * A_new; B_new = app.T02 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'g');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'g'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'g'); % Mặt đáy trên

            %% --------------- Vẽ Link 2 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10);  % Bán kính 10
        Z = Z * 124 - 124;           % Chiều cao và dịch trục Z
        
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T03 * A_new; B_new = app.T03 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'g');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'g'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'g'); % Mặt đáy trên
               %% --------------- Vẽ Link 3 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(6);  % Bán kính 10
        Z = Z * 126-126 ;           % Chiều cao và dịch trục Z
            
        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T04 * A_new; B_new = app.T04 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        
        % Vẽ Link 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'g');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'g'); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'g'); % Mặt đáy trên
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Draw_Robot();
        end

        % Value changed function: theta1Slider
        function theta1SliderValueChanged(app, event)
               app.old_theta(1) = app.theta(1);
               app.new_theta(1) = app.theta1Slider.Value;
               app.theta_box_1.Value = app.new_theta(1);
               app.new_theta(1) = deg2rad(app.new_theta(1));
        end

        % Value changed function: theta2Slider
        function theta2SliderValueChanged(app, event)
               app.old_theta(2) = app.theta(2);
               app.new_theta(2) = app.theta2Slider.Value;
               app.theta_box_2.Value = app.new_theta(2);
               app.new_theta(2) = deg2rad(app.new_theta(2));       
        end

        % Value changed function: theta3Slider
        function theta3SliderValueChanged(app, event)
               app.old_theta(3) = app.theta(3);
               app.new_theta(3) = app.theta3Slider.Value;
               app.theta_box_3.Value = app.new_theta(3);
               app.new_theta(3) = deg2rad(app.new_theta(3));   
            
        end

        % Value changed function: theta4Slider
        function theta4SliderValueChanged(app, event)
               app.old_theta(4) = app.theta(4);
               app.new_theta(4) = app.theta4Slider.Value;
               app.theta_box_4.Value = app.new_theta(4);
               app.new_theta(4) = deg2rad(app.new_theta(4));   
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)


            app.old_theta(1) = app.theta(1);
            app.new_theta(1) = app.theta1Slider.Value;
            app.theta_box_1.Value = app.new_theta(1);
            app.new_theta(1) = deg2rad(app.new_theta(1));


            app.old_theta(2) = app.theta(2);
            app.new_theta(2) = app.theta2Slider.Value;
            app.theta_box_2.Value = app.new_theta(2);
            app.new_theta(2) = deg2rad(app.new_theta(2)); 

            app.old_theta(3) = app.theta(3);
            app.new_theta(3) = app.theta3Slider.Value;
            app.theta_box_3.Value = app.new_theta(3);
            app.new_theta(3) = deg2rad(app.new_theta(3));  

            app.old_theta(4) = app.theta(4);
            app.new_theta(4) = app.theta4Slider.Value;
            app.theta_box_4.Value = app.new_theta(4);
            app.new_theta(4) = deg2rad(app.new_theta(4));  
            % app.Draw_Robot();
                        %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(4) = DiscreteTheta4(i);
                app.Draw_Robot();
                pause(0.01);
            end
                
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.old_theta(1) = app.theta(1);
            app.new_theta(1) = 0;
            app.theta_box_1.Value = app.new_theta(1);
            app.new_theta(1) = deg2rad(app.new_theta(1));


            app.old_theta(2) = app.theta(2);
            app.new_theta(2) = 90;
            app.theta_box_2.Value = app.new_theta(2);
            app.new_theta(2) = deg2rad(app.new_theta(2)); 

            app.old_theta(3) = app.theta(3);
            app.new_theta(3) = -90;
            app.theta_box_3.Value = app.new_theta(3);
            app.new_theta(3) = deg2rad(app.new_theta(3));  

            app.old_theta(4) = app.theta(4);
            app.new_theta(4) = 0;
            app.theta_box_4.Value = app.new_theta(4);
            app.new_theta(4) = deg2rad(app.new_theta(4));  
            % app.Draw_Robot();
                        %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(4) = DiscreteTheta4(i);
                app.Draw_Robot();
                pause(0.01);
            end
              
            
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(app, event)
            app.robotPath = [];
            app.Draw_Robot();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator-X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-600 600];
            app.UIAxes.YLim = [-600 600];
            app.UIAxes.ZLim = [0 600];
            app.UIAxes.XTick = [-600 -500 -400 -300 -200 -100 0 100 200 300 400 500 600];
            app.UIAxes.XTickLabel = {'-600'; '-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxes.YTick = [-300 -200 -100 0 100 200 300];
            app.UIAxes.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.UIAxes.ZTick = [0 50 100 150 200 250 300 350 400];
            app.UIAxes.ZTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'};
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.theta1SliderLabel = uilabel(app.INPUTPanel);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.INPUTPanel);
            app.theta1Slider.Limits = [-180 180];
            app.theta1Slider.ValueChangedFcn = createCallbackFcn(app, @theta1SliderValueChanged, true);
            app.theta3SliderLabel = uilabel(app.INPUTPanel);
            app.theta3SliderLabel.Text = 'theta3';
            app.theta3Slider = uislider(app.INPUTPanel);
            app.theta3Slider.Limits = [-180 180];
            app.theta3Slider.ValueChangedFcn = createCallbackFcn(app, @theta3SliderValueChanged, true);
            app.theta4SliderLabel = uilabel(app.INPUTPanel);
            app.theta4SliderLabel.Text = 'theta4';
            app.theta4Slider = uislider(app.INPUTPanel);
            app.theta4Slider.Limits = [-180 180];
            app.theta4Slider.ValueChangedFcn = createCallbackFcn(app, @theta4SliderValueChanged, true);
            app.theta2SliderLabel = uilabel(app.INPUTPanel);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.INPUTPanel);
            app.theta2Slider.Limits = [-180 180];
            app.theta2Slider.ValueChangedFcn = createCallbackFcn(app, @theta2SliderValueChanged, true);
            app.ForwardButton = uibutton(app.INPUTPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.ResetButton = uibutton(app.INPUTPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.theta_box_1 = uieditfield(app.INPUTPanel, 'numeric');
            app.theta_box_2 = uieditfield(app.INPUTPanel, 'numeric');
            app.theta_box_3 = uieditfield(app.INPUTPanel, 'numeric');
            app.theta_box_4 = uieditfield(app.INPUTPanel, 'numeric');
            app.ClearButton = uibutton(app.INPUTPanel, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.ClearButton.Text = 'Clear';
            app.OUTPUTPanel = uipanel(app.UIFigure);
            app.OUTPUTPanel.Title = 'OUTPUT';
            app.PxEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PyEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PzEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTL_KTRB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end